classdef animator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECODATAAnimateUIFigure          matlab.ui.Figure
        LoadsettingsButton              matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        GeneratelastframeonlyCheckBox   matlab.ui.control.CheckBox
        FilepathTextArea_output         matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        SetoutputdirectoryButton        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnimaltrackdataTab              matlab.ui.container.Tab
        DatafiltersPanel                matlab.ui.container.Panel
        NumberofpointsinselectionTextArea  matlab.ui.control.TextArea
        NumberofpointsinselectionTextAreaLabel  matlab.ui.control.Label
        LatesttimestampinselectionTextArea  matlab.ui.control.TextArea
        LatesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        EarliesttimestampinselectionTextArea  matlab.ui.control.TextArea
        EarliesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        NumberofindividualsselectedTextArea  matlab.ui.control.TextArea
        NumberofindividualsselectedTextAreaLabel  matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        SelectionsummaryLabel           matlab.ui.control.Label
        UpdatefiltersButton             matlab.ui.control.Button
        GeographicextentLabel           matlab.ui.control.Label
        TimerangeLabel                  matlab.ui.control.Label
        IndividualsLabel                matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        AllindividualsNode              matlab.ui.container.TreeNode
        FilepathTextArea_tracks         matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        SelecttrackdataButton           matlab.ui.control.Button
        TracksvisualizationoptionsTab   matlab.ui.container.Tab
        VisualizationoptionsLabel       matlab.ui.control.Label
        UITable_colors                  matlab.ui.control.Table
        UsecustomcolorsCheckBox         matlab.ui.control.CheckBox
        UseblackmarkersCheckBox         matlab.ui.control.CheckBox
        ShowlegendCheckBox              matlab.ui.control.CheckBox
        ClearcolorsButton               matlab.ui.control.Button
        MarkersizeSpinner_tracks        matlab.ui.control.Spinner
        MarkersizeSpinner_2Label        matlab.ui.control.Label
        TrackopacitySpinner             matlab.ui.control.Spinner
        TrackopacitySpinnerLabel        matlab.ui.control.Label
        TrackfrequencyhoursSpinner      matlab.ui.control.Spinner
        TrackfrequencyhoursSpinnerLabel  matlab.ui.control.Label
        ColortracksbyDropDown           matlab.ui.control.DropDown
        ColortracksbyDropDownLabel      matlab.ui.control.Label
        TracklinewidthSpinner           matlab.ui.control.Spinner
        TracklinewidthSpinnerLabel      matlab.ui.control.Label
        MarkerstyleDropDown             matlab.ui.control.DropDown
        MarkerstyleDropDownLabel        matlab.ui.control.Label
        TrackmemoryEditField            matlab.ui.control.NumericEditField
        TrackmemoryEditFieldLabel       matlab.ui.control.Label
        FadetracksCheckBox              matlab.ui.control.CheckBox
        AddcolorButton                  matlab.ui.control.Button
        EnvironmentaldataTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        GriddedcolormapTab              matlab.ui.container.Tab
        GriddeddataLabel                matlab.ui.control.Label
        ColoroptionsLabel               matlab.ui.control.Label
        DatasetdimensionsLabel          matlab.ui.control.Label
        ColorbarmaxEditField            matlab.ui.control.NumericEditField
        ColorbarmaxEditFieldLabel       matlab.ui.control.Label
        VariabletoanimateDropDown_gridded  matlab.ui.control.DropDown
        VariabletoanimateDropDownLabel  matlab.ui.control.Label
        ColorbarminEditField            matlab.ui.control.NumericEditField
        ColorbarminEditFieldLabel       matlab.ui.control.Label
        FilepathTextArea_gridded        matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        LongitudevariableDropDownLabel  matlab.ui.control.Label
        LongitudevariableDropDown_gridded  matlab.ui.control.DropDown
        LatitudevariableLabel           matlab.ui.control.Label
        LatitudevariableDropDown_gridded  matlab.ui.control.DropDown
        TimevariableLabel               matlab.ui.control.Label
        TimevariableDropDown_gridded    matlab.ui.control.DropDown
        ColormapDropDown_gridded        matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        AutomaticcolorbarlimitsCheckBox  matlab.ui.control.CheckBox
        ShowcolorbarCheckBox            matlab.ui.control.CheckBox
        CleardataButton_gridded         matlab.ui.control.Button
        InvertcolormapCheckBox          matlab.ui.control.CheckBox
        SelectgriddeddataButton         matlab.ui.control.Button
        ContoursTab                     matlab.ui.container.Tab
        ContouroptionsLabel             matlab.ui.control.Label
        DatasetdimensionsLabel_2        matlab.ui.control.Label
        ContourlinewidthSpinner         matlab.ui.control.Spinner
        ContourlinewidthSpinnerLabel    matlab.ui.control.Label
        ContourlinecolorDropDown        matlab.ui.control.DropDown
        ContourlinecolorDropDownLabel   matlab.ui.control.Label
        FilepathTextArea_2Label_6       matlab.ui.control.Label
        CleardataButton_contour         matlab.ui.control.Button
        LongitudevariableDropDownLabel_2  matlab.ui.control.Label
        LatitudevariableLabel_2         matlab.ui.control.Label
        TimevariableLabel_2             matlab.ui.control.Label
        VariabletoanimateDropDownLabel_2  matlab.ui.control.Label
        ShowcontourtextCheckBox         matlab.ui.control.CheckBox
        LongitudevariableDropDown_contour  matlab.ui.control.DropDown
        LatitudevariableDropDown_contour  matlab.ui.control.DropDown
        TimevariableDropDown_contour    matlab.ui.control.DropDown
        VariabletoanimateDropDown_contour  matlab.ui.control.DropDown
        FilepathTextArea_contour        matlab.ui.control.TextArea
        SelectcontourdataButton         matlab.ui.control.Button
        ContourdataLabel                matlab.ui.control.Label
        VectorfieldsTab                 matlab.ui.container.Tab
        AutoscaleCheckBox               matlab.ui.control.CheckBox
        QuiverspeedSpinner              matlab.ui.control.Spinner
        QuiverspeedSpinnerLabel         matlab.ui.control.Label
        QuiversizeSpinner               matlab.ui.control.Spinner
        ContourlinewidthSpinner_2Label_2  matlab.ui.control.Label
        QuivercolorDropDown             matlab.ui.control.DropDown
        ContourlinecolorDropDown_2Label_2  matlab.ui.control.Label
        VectoroptionsLabel              matlab.ui.control.Label
        DatasetdimensionsLabel_3        matlab.ui.control.Label
        U_vector_DropDown               matlab.ui.control.DropDown
        UvectorLabel                    matlab.ui.control.Label
        LongitudevariableDropDown_vectorfield  matlab.ui.control.DropDown
        LongitudevariableDropDownLabel_3  matlab.ui.control.Label
        LatitudevariableDropDown_vectorfield  matlab.ui.control.DropDown
        LatitudevariableLabel_3         matlab.ui.control.Label
        TimevariableDropDown_vectorfield  matlab.ui.control.DropDown
        TimevariableLabel_3             matlab.ui.control.Label
        FilepathTextArea_vectorfield    matlab.ui.control.TextArea
        FilepathTextArea_2Label_7       matlab.ui.control.Label
        V_vector_DropDown               matlab.ui.control.DropDown
        VvectorLabel                    matlab.ui.control.Label
        VectorfielddatawindcurrentsLabel  matlab.ui.control.Label
        CleardataButton_vectorfield     matlab.ui.control.Button
        SelectvectorfielddataButton     matlab.ui.control.Button
        ShapefilesTab                   matlab.ui.container.Tab
        MarkersizeSpinner_shapefile     matlab.ui.control.Spinner
        MarkersizeSpinnerLabel_2        matlab.ui.control.Label
        MarkerstyleDropDown_shapefile   matlab.ui.control.DropDown
        MarkerstyleDropDown_2Label      matlab.ui.control.Label
        PointcolorDropDown_shapefile    matlab.ui.control.DropDown
        PointcolorDropDownLabel         matlab.ui.control.Label
        PolygonoptionsLabel             matlab.ui.control.Label
        PointoptionsLabel               matlab.ui.control.Label
        LineoptionsLabel                matlab.ui.control.Label
        ClearlastshapefileButton        matlab.ui.control.Button
        ClearallshapefilesButton        matlab.ui.control.Button
        UITable_shapefiles              matlab.ui.control.Table
        FaceopacitySpinner              matlab.ui.control.Spinner
        FaceopacitySpinnerLabel         matlab.ui.control.Label
        EdgecolorDropDown               matlab.ui.control.DropDown
        EdgecolorDropDownLabel          matlab.ui.control.Label
        FacecolorDropDown               matlab.ui.control.DropDown
        FacecolorDropDownLabel          matlab.ui.control.Label
        LinewidthSpinner                matlab.ui.control.Spinner
        LinewidthSpinnerLabel           matlab.ui.control.Label
        LinecolorDropDown               matlab.ui.control.DropDown
        LinecolorDropDownLabel          matlab.ui.control.Label
        FilepathTextArea_2Label_2       matlab.ui.control.Label
        FilepathTextArea_shapefile      matlab.ui.control.TextArea
        SelectshapefileButton           matlab.ui.control.Button
        LabeledpointsTab                matlab.ui.container.Tab
        UITable_colors_labeledpoints    matlab.ui.control.Table
        UsecustomcolorsCheckBox_labeledPoints  matlab.ui.control.CheckBox
        ClearcolorsButton_labeledPoints  matlab.ui.control.Button
        AddcolorButton_labeledPoints    matlab.ui.control.Button
        ColorpointsbyattributeCheckBox  matlab.ui.control.CheckBox
        ColorpointsbyDropDown           matlab.ui.control.DropDown
        ColorpointsbyDropDownLabel      matlab.ui.control.Label
        LabelsizeSpinner_labeledPoints  matlab.ui.control.Spinner
        Labelsize                       matlab.ui.control.Label
        LabelcolorDropDown_labeledPoints  matlab.ui.control.DropDown
        Labelcolor                      matlab.ui.control.Label
        ShowlabeltextCheckBox           matlab.ui.control.CheckBox
        CleardataButton_labeledPoints   matlab.ui.control.Button
        MarkersizeSpinner_labeledPoints  matlab.ui.control.Spinner
        MarkersizeSpinnerLabel          matlab.ui.control.Label
        MarkercolorDropDown_labeledPoints  matlab.ui.control.DropDown
        MarkercolorDropDownLabel        matlab.ui.control.Label
        FilepathTextAreaLabel_labeledPoints  matlab.ui.control.Label
        FilepathTextArea_labeledPoints  matlab.ui.control.TextArea
        SelectfileButton_labeledPoints  matlab.ui.control.Button
        Labeledpoints                   matlab.ui.control.Label
        ElevationTab                    matlab.ui.container.Tab
        ContourlinecolorDropDown_elevation  matlab.ui.control.DropDown
        ContourlinecolorDropDown_2Label  matlab.ui.control.Label
        NumberoflevelsSpinner           matlab.ui.control.Spinner
        NumberoflevelsSpinnerLabel      matlab.ui.control.Label
        IncludeelevationcontoursCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        ContourlinewidthSpinner_elevation  matlab.ui.control.Spinner
        ContourlinewidthSpinner_2Label  matlab.ui.control.Label
        ShowcontourtextCheckBox_elevation  matlab.ui.control.CheckBox
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        CreateanimationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        gridded_data
        contour_data
        quiver_data
        elevation
        shapefile_stack
        raster_image
        labeled_points
        start_time
        end_time
        raw_track_data % input dataset
        tracks
        output_dir
        selected_individuals
        track_colors
        labeled_points_colors
    end
    
    methods (Access = private)

        function update_status_text(app, status_text)
            try
                app.StatusTextArea.Value = status_text;
                drawnow;
            catch
            end
        end

        function save_settings(app)
            try
                props = properties(app);
                values = cell(1, length(props));
                data = cell(1, length(props));
                items = cell(1, length(props));
                visibilities = cell(1, length(props));
                
                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        values{i} = app.(propName).Value;
                    end
                    if isprop(property, 'Data')
                        data{i} = app.(propName).Data;
                    end
                    if isprop(property, 'Items')
                        items{i} = app.(propName).Items;
                    end
                    if isprop(property, 'Visible')
                        visibilities{i} = app.(props{i}).Visible;
                    end
                end


                settings_fname = 'settings.mat';
                vars_to_save =  ["gridded_data" "contour_data" "quiver_data" "elevation" ...
                     "shapefile_stack" "raster_image" "labeled_points" ... 
                     "start_time" "end_time" ...
                     "raw_track_data" "tracks" "output_dir" ...
                     "selected_individuals" "track_colors" "labeled_points_colors"];

                if isfolder(app.output_dir)
                    save_path = fullfile(app.output_dir, settings_fname);
                    
                    var_map = containers.Map;
                    for i=1:length(vars_to_save)
                        var_map(vars_to_save(i)) = app.(vars_to_save(i));
                    end

                    save(save_path, "props", "values", "data", "items", "var_map", "visibilities", "var_map")

                else
                    app.update_status_text("Please select a valid output directory");
                end
            catch e
                app.update_status_text(e.getReport);
            end
        end
        
        function update_widgets_from_track_data(app)
            try
                % Update the list box with the available individuals 
                individuals = unique(app.raw_track_data.individual_local_identifier);
    
                % Update tree 
                for i =1:length(individuals)
                    uitreenode(app.AllindividualsNode, "Text", individuals{i});
                end
                expand(app.Tree);
    
                % Add attributes to the color grouping selector
                app.ColortracksbyDropDown.Items = app.raw_track_data.Properties.VariableNames;
            catch e
                app.update_status_text(e.getReport);
            end
        end
        
        function apply_track_filters(app)
            try
                % Reset the tracks data to the raw version to get rid of
                % previous filters
                app.tracks.data = app.raw_track_data;

                % Select time window 
                app.tracks.select_timerange(app.start_time, app.end_time);
    
                % Select extent 
                app.tracks.select_bbox(app.MinlatitudeEditField.Value, app.MaxlatitudeEditField.Value, ... 
                    app.MinlongitudeEditField.Value, app.MaxlongitudeEditField.Value);

                % Select individuals 
                app.tracks.select_individuals(app.selected_individuals);
            catch e
                app.update_status_text(e.getReport);
            end
            
        end
        
        
        function autoselect_valid_varnames(app, dataset, time_dropdown, lat_dropdown, lon_dropdown, var_dropdown)
            try
                potential_timevars = {'time', 'timestamp'};
                potential_latvars = {'lat', 'latitude'};
                potential_lonvars = {'lon', 'long', 'longitude'};
    
                matched_vars = {};
                
                if isa(dataset, 'GriddedData')
                    data_varnames = dataset.varnames;
                elseif isa(dataset, 'Quivers')
                    data_varnames = dataset.varnames;
                else
                    data_varnames = dataset("varnames");
                end
    
                matched_timevar = intersect(data_varnames, potential_timevars);
                if length(matched_timevar) >= 1
                    app.(time_dropdown).Value = matched_timevar{1};
                    matched_vars(1+length(matched_vars)) = matched_timevar(1);
                end
    
                matched_latvar = intersect(data_varnames, potential_latvars);
                if length(matched_latvar) >= 1
                    app.(lat_dropdown).Value = matched_latvar{1};
                    matched_vars(1+length(matched_vars)) = matched_latvar(1);
    
                end
    
                matched_lonvar = intersect(data_varnames, potential_lonvars);
                if length(matched_lonvar) >= 1
                    app.(lon_dropdown).Value = matched_lonvar{1};
                    matched_vars(1+length(matched_vars)) = matched_lonvar(1);
    
                end
    
                % Update the var of interest to the 1st var not matching time,
                % lat, or lon
                other_vars = data_varnames(~ismember(data_varnames,matched_vars));
                if length(matched_timevar) >= 1
                    if isstruct(var_dropdown)
                        app.(getfield(var_dropdown, 'u')).Value = other_vars{1};
                        app.(getfield(var_dropdown, 'v')).Value = other_vars{2};

                    else   
                        app.(var_dropdown).Value= other_vars{1};
                    end
                end
            catch e
                app.update_status_text(e.getReport);
            end                
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Initialize inputs for the animation function 
                app.gridded_data = {};
                app.contour_data = containers.Map();
                app.quiver_data = {};
                app.shapefile_stack = {};
                app.raster_image = containers.Map();
                app.labeled_points = containers.Map();
                app.elevation = containers.Map();
                app.track_colors = [];
                app.labeled_points_colors=[];
                
            catch e
                app.update_status_text(e.getReport);
            end

        end

        % Button pushed function: SelecttrackdataButton
        function SelecttrackdataButtonPushed(app, event)
            try

                % Make sure individual nodes are cleared
                individual_nodes = app.AllindividualsNode.Children;
                individual_nodes.delete;

                % Open file browser to select input data 
                [input_datafile, input_path] = uigetfile('*.csv', 'select');
                input_fullpath = fullfile(input_path, input_datafile);
                app.FilepathTextArea_tracks.Value = input_fullpath;
                
                %load the data
                app.update_status_text("Loading data...");
                app.tracks = Tracks(input_fullpath);
                app.update_status_text("Track data loaded.");

                app.raw_track_data = app.tracks.data;
    
                %update the geolimits 
                [latlim, lonlim] = get_geolimits(app.raw_track_data, .10);
    
                app.MinlatitudeEditField.Value = latlim(1);
                app.MaxlatitudeEditField.Value = latlim(2);
                app.MinlongitudeEditField.Value = lonlim(1);
                app.MaxlongitudeEditField.Value = lonlim(2);
    
                % set default start and end times 
                app.start_time = min(app.raw_track_data.timestamp);
                app.end_time = max(app.raw_track_data.timestamp);
                app.StartdateDatePicker.Value = app.start_time;
                app.EnddateDatePicker.Value = app.end_time;
        
    
                app.update_widgets_from_track_data();

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            try
                checkedNodes = app.Tree.CheckedNodes;
                app.selected_individuals = {checkedNodes.Text};
            catch e
                app.update_status_text(e.getReport);
            end
            
        end

        % Button pushed function: SelectgriddeddataButton
        function SelectgriddeddataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [gridded_datafile, gridded_path] = uigetfile('*.nc', 'select');
                app.gridded_data = GriddedData(fullfile(gridded_path, gridded_datafile));
                app.FilepathTextArea_gridded.Value = app.gridded_data.filename;
                
                % Get variable names 
                app.VariabletoanimateDropDown_gridded.Items = app.gridded_data.varnames;
                app.TimevariableDropDown_gridded.Items = app.gridded_data.varnames; 
                app.LatitudevariableDropDown_gridded.Items = app.gridded_data.varnames; 
                app.LongitudevariableDropDown_gridded.Items = app.gridded_data.varnames;
                
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.gridded_data, 'TimevariableDropDown_gridded', ...
                    'LatitudevariableDropDown_gridded', 'LongitudevariableDropDown_gridded', ...
                    'VariabletoanimateDropDown_gridded')
            catch e
                app.update_status_text(e.getReport);
            end
 
        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)
            try
                
                app.update_status_text("Generating animation... Please be patient");
    
                app.save_settings()

                % tracks colors
                if app.UsecustomcolorsCheckBox.Value && ~isempty(app.track_colors)
                    track_cmap = cmap_from_colors(app.track_colors);
                else
                    track_cmap = lines;
                end

                % Track marker color 
                if app.UseblackmarkersCheckBox.Value
                    track_marker_color = 'k';
                else
                    track_marker_color = NaN;
                end

                % Gather gridded data info 
                if ~isempty(app.gridded_data)
                    app.gridded_data.latvar = app.LatitudevariableDropDown_gridded.Value;
                    app.gridded_data.lonvar = app.LongitudevariableDropDown_gridded.Value;
                    app.gridded_data.timevar = app.TimevariableDropDown_gridded.Value;
                    app.gridded_data.var_of_interest = app.VariabletoanimateDropDown_gridded.Value;
                    app.gridded_data.cmap = app.ColormapDropDown_gridded.Value;
                    app.gridded_data.invert_cmap = app.InvertcolormapCheckBox.Value;
                    if app.AutomaticcolorbarlimitsCheckBox.Value
                        app.gridded_data.cbar_limits = [];
                    else
                        app.gridded_data.cbar_limits = [app.ColorbarminEditField.Value, app.ColorbarmaxEditField.Value];
                    end
                    app.gridded_data.show_colorbar = app.ShowcolorbarCheckBox.Value;
                    app.gridded_data.load_time_index();
                end
    
                % Gather contour data info 
                if isKey(app.contour_data, 'filename')
                    app.contour_data('latvar') = app.LatitudevariableDropDown_contour.Value;
                    app.contour_data('lonvar') = app.LongitudevariableDropDown_contour.Value; 
                    app.contour_data('timevar') = app.TimevariableDropDown_contour.Value;
                    app.contour_data('var_of_interest') = app.VariabletoanimateDropDown_contour.Value;
                    app.contour_data('LineColor') = app.ContourlinecolorDropDown.Value;
                    app.contour_data('LineWidth') = app.ContourlinewidthSpinner.Value;
                    if app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'on'; 
                    elseif ~app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'off'; end
                end

                % Gather quiver data info 
                if ~isempty(app.quiver_data)

                    % Variable and dimension labels
                    app.quiver_data.latvar = app.LatitudevariableDropDown_vectorfield.Value;
                    app.quiver_data.lonvar = app.LongitudevariableDropDown_vectorfield.Value;
                    app.quiver_data.timevar = app.TimevariableDropDown_vectorfield.Value;
                    app.quiver_data.u_var = app.U_vector_DropDown.Value;
                    app.quiver_data.v_var = app.V_vector_DropDown.Value;

                    % Quiver settings
                    app.quiver_data.quiver_color = app.QuivercolorDropDown.Value;
                    app.quiver_data.quiver_speed = app.QuiverspeedSpinner.Value;
                    if app.AutoscaleCheckBox.Value
                        app.quiver_data.quiver_size = 0;
                    else
                        app.quiver_data.quiver_size= app.QuiversizeSpinner.Value;
                    end
                    
                    app.quiver_data.load_time_index();
                end

                % Gather elevation info 
                if app.IncludeelevationcontoursCheckBox.Value
                    app.elevation('LineColor') = app.ContourlinecolorDropDown_elevation.Value;
                    app.elevation('nlevels') = app.NumberoflevelsSpinner.Value;
                    app.elevation('LineWidth') = app.ContourlinewidthSpinner_elevation.Value;
                    app.elevation('ShowText') = app.ShowcontourtextCheckBox_elevation.Value;
                end

                % Gather labeled points info 
                if isKey(app.labeled_points, 'filename')
                    app.labeled_points('marker_color') = app.MarkercolorDropDown_labeledPoints.Value;
                    app.labeled_points('marker_size') = app.MarkersizeSpinner_labeledPoints.Value;
                    app.labeled_points('text_size') = app.LabelsizeSpinner_labeledPoints.Value;
                    app.labeled_points('text_color') = app.LabelcolorDropDown_labeledPoints.Value;
                    app.labeled_points('show_text') = app.ShowlabeltextCheckBox.Value;

                    if app.ColorpointsbyattributeCheckBox.Value
                        app.labeled_points('group_by') = app.ColorpointsbyDropDown.Value;
                    else
                        app.labeled_points('group_by') = NaN;
                    end

                    % labeled points colors
                    if app.ColorpointsbyattributeCheckBox.Value && app.UsecustomcolorsCheckBox_labeledPoints.Value && ~isempty(app.labeled_points_colors)
                        labeledpoints_cmap = cmap_from_colors(app.labeled_points_colors);
                    else
                        labeledpoints_cmap = lines;
                    end
                    labeled_points_loaded = LabeledPoints(app.labeled_points('filename'), marker_color=app.labeled_points('marker_color'), ...
                        marker_size=app.labeled_points('marker_size'), text_color=app.labeled_points('text_color'), ...
                        text_size=app.labeled_points('text_size'), show_text=app.labeled_points('show_text'), ...
                        group_by=app.labeled_points('group_by'), cmap=labeledpoints_cmap);
                else
                    labeled_points_loaded=containers.Map();
                end

                % Update tracks info
                app.tracks.track_cmap = track_cmap;
                app.tracks.frequency = hours(app.TrackfrequencyhoursSpinner.Value);
                app.tracks.track_alpha = app.TrackopacitySpinner.Value;
                app.tracks.marker_color = track_marker_color;
                app.tracks.marker_size = app.MarkersizeSpinner_tracks.Value;
                app.tracks.marker_style = app.MarkerstyleDropDown.Value;
                app.tracks.fade_tracks = app.FadetracksCheckBox.Value;
                app.tracks.track_memory = app.TrackmemoryEditField.Value;
                app.tracks.group_by = app.ColortracksbyDropDown.Value;
                app.tracks.track_width = app.TracklinewidthSpinner.Value;
    
                animate_tracks(app.tracks,...
                    gridded_data=app.gridded_data, ...
                    contour_data=app.contour_data, ...
                    quiver_data=app.quiver_data, ...
                    elevation=app.elevation, ...
                    shapefile_stack=app.shapefile_stack, ...
                    labeled_points=labeled_points_loaded, ...
                    start_time=dateshift(app.start_time, 'start', 'day'), ...
                    end_time=dateshift(app.end_time, 'start', 'day'), ...
                    output_directory=app.output_dir, ...
                    latmin=app.MinlatitudeEditField.Value, latmax=app.MaxlatitudeEditField.Value, ...
                    lonmin=app.MinlongitudeEditField.Value, lonmax=app.MaxlongitudeEditField.Value, ...
                    last_frame_only=app.GeneratelastframeonlyCheckBox.Value, ...
                    show_legend=app.ShowlegendCheckBox.Value)
    
                app.update_status_text("Animation saved to the output directory.");
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SetoutputdirectoryButton
        function SetoutputdirectoryButtonPushed(app, event)
            try
                app.output_dir = uigetdir();
                app.FilepathTextArea_output.Value = app.output_dir; 
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            try
                app.start_time = app.StartdateDatePicker.Value;
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            try
                app.end_time = app.EnddateDatePicker.Value;
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Button pushed function: UpdatefiltersButton
        function UpdatefiltersButtonPushed(app, event)
            try
                app.apply_track_filters()
                app.NumberofpointsinselectionTextArea.Value = num2str(height(app.tracks.data));
                app.NumberofindividualsselectedTextArea.Value = num2str(length(unique(app.tracks.data.individual_local_identifier)));
                app.EarliesttimestampinselectionTextArea.Value = string(min(app.tracks.data.timestamp));
                app.LatesttimestampinselectionTextArea.Value = string(max(app.tracks.data.timestamp));
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SelectcontourdataButton
        function SelectcontourdataButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.nc', 'select');
                app.contour_data('filename') = fullfile(path, datafile);
                app.FilepathTextArea_contour.Value = app.contour_data('filename');
                
                % Get variable names 
                varnames= {ncinfo(app.contour_data('filename')).Variables.Name};
                app.VariabletoanimateDropDown_contour.Items = varnames;
                app.TimevariableDropDown_contour.Items = varnames; 
                app.LatitudevariableDropDown_contour.Items = varnames; 
                app.LongitudevariableDropDown_contour.Items = varnames;

                app.contour_data("varnames") = varnames;

            
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.contour_data, 'TimevariableDropDown_contour', ...
                    'LatitudevariableDropDown_contour', 'LongitudevariableDropDown_contour', ...
                    'VariabletoanimateDropDown_contour')
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SelectshapefileButton
        function SelectshapefileButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.shp', 'select');
                filename = fullfile(path, datafile);
                app.FilepathTextArea_shapefile.Value = filename;

                shapefile = Shapefile(filename);

                if shapefile.is_point()
                    app.PointcolorDropDown_shapefile.Editable = true;
                    app.PointcolorDropDown_shapefile.Enable = true;
                    app.MarkersizeSpinner_shapefile.Editable = true;
                    app.MarkersizeSpinner_shapefile.Enable = true;
                    app.MarkerstyleDropDown_shapefile.Editable = true;
                    app.MarkerstyleDropDown_shapefile.Enable = true;
                    app.LinecolorDropDown.Editable=false;
                    app.LinecolorDropDown.Enable=false;
                    app.LinewidthSpinner.Editable=false;
                    app.LinewidthSpinner.Enable=false;
                    app.EdgecolorDropDown.Editable = false;
                    app.EdgecolorDropDown.Enable = false;
                    app.FacecolorDropDown.Editable = false;
                    app.FacecolorDropDown.Enable = false; 
                    app.FaceopacitySpinner.Editable = false;
                    app.FaceopacitySpinner.Enable = false; 
                    shapefile.point_color = app.PointcolorDropDown_shapefile.Value;
                    shapefile.marker_style = app.MarkerstyleDropDown_shapefile.Value;    
                    shapefile.marker_size = app.MarkersizeSpinner_shapefile.Value;
                    point_line_edge_color = app.PointcolorDropDown_shapefile.Value;
                elseif shapefile.is_line()
                    app.PointcolorDropDown_shapefile.Editable = false;
                    app.PointcolorDropDown_shapefile.Enable = false;
                    app.MarkersizeSpinner_shapefile.Editable = false;
                    app.MarkersizeSpinner_shapefile.Enable = false;
                    app.MarkerstyleDropDown_shapefile.Editable = false;
                    app.MarkerstyleDropDown_shapefile.Enable = false;                    
                    app.LinecolorDropDown.Editable=true;
                    app.LinecolorDropDown.Enable=true;
                    app.LinewidthSpinner.Editable=true;
                    app.LinewidthSpinner.Enable=true;
                    app.EdgecolorDropDown.Editable = false;
                    app.EdgecolorDropDown.Enable = false;
                    app.FacecolorDropDown.Editable = false;
                    app.FacecolorDropDown.Enable = false; 
                    app.FaceopacitySpinner.Editable = false;
                    app.FaceopacitySpinner.Enable = false; 
                    shapefile.line_color = app.LinecolorDropDown.Value;
                    shapefile.line_width = app.LinewidthSpinner.Value;
                    point_line_edge_color = app.LinecolorDropDown.Value;
                elseif shapefile.is_poly()
                    app.PointcolorDropDown_shapefile.Editable = false;
                    app.PointcolorDropDown_shapefile.Enable = false;
                    app.MarkersizeSpinner_shapefile.Editable = false;
                    app.MarkersizeSpinner_shapefile.Enable = false;
                    app.MarkerstyleDropDown_shapefile.Editable = false;
                    app.MarkerstyleDropDown_shapefile.Enable = false;                    
                    app.EdgecolorDropDown.Editable = true;
                    app.EdgecolorDropDown.Enable = true;
                    app.FacecolorDropDown.Editable = true;
                    app.FacecolorDropDown.Enable = true; 
                    app.FaceopacitySpinner.Editable = true;
                    app.FaceopacitySpinner.Enable = true; 
                    app.LinecolorDropDown.Editable=false;
                    app.LinecolorDropDown.Enable=false;
                    app.LinewidthSpinner.Editable=false;
                    app.LinewidthSpinner.Enable=false;
                    shapefile.edge_color= app.EdgecolorDropDown.Value;
                    shapefile.face_color = app.FacecolorDropDown.Value; 
                    shapefile.face_alpha = app.FaceopacitySpinner.Value; 
                    point_line_edge_color = app.EdgecolorDropDown.Value;
                    
                end

                % Update table display

                if length(app.shapefile_stack)<1
                    % Initialize table if needed
                    varnames = ["File","Geometry","Point/Line/Edge color","Marker style","Marker size","Line width","Face color","Face opacity"];
                    vartypes = ["string", "string", "string", "string", "double", "double", "string", "double"];
                    tablesize=[0,8];
                    app.UITable_shapefiles.Data = table('VariableNames', varnames, 'VariableTypes', vartypes, 'Size', tablesize);
                end

                app.UITable_shapefiles.Data(end +1, :) = {datafile, shapefile.geometry_type, point_line_edge_color, shapefile.marker_style, shapefile.marker_size, shapefile.line_width, shapefile.face_color, shapefile.face_alpha};

                app.shapefile_stack{end+1} = shapefile;

                % Disable adding new shapefiles if at max limit
                max_shapefiles = 15;
                if length(app.shapefile_stack) >= max_shapefiles
                    app.SelectshapefileButton.Enable = false;
                end

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: LinecolorDropDown
        function LinecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.line_color = app.LinecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.LinecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Value changed function: LinewidthSpinner
        function LinewidthSpinnerValueChanged(app, event)
            try
                app.shapefile_stack{end}.line_width = app.LinewidthSpinner.Value;
                app.UITable_shapefiles.Data(end, "Line width") = {app.LinewidthSpinner.Value};
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: EdgecolorDropDown
        function EdgecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.edge_color = app.EdgecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.EdgecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end           
        end

        % Value changed function: FacecolorDropDown
        function FacecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.face_color = app.FacecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Face color") = {app.FacecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end       
        end

        % Value changed function: FaceopacitySpinner
        function FaceopacitySpinnerValueChanged(app, event)
            try
                app.shapefile_stack{end}.face_alpha = app.FaceopacitySpinner.Value;
                app.UITable_shapefiles.Data(end, "Face opacity") = {app.FaceopacitySpinner.Value};
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Button pushed function: SelectfileButton_labeledPoints
        function SelectfileButton_labeledPointsPushed(app, event)
            try
                [datafile, path] = uigetfile('*.csv', 'select');
                app.labeled_points('filename') = fullfile(path, datafile);
                app.FilepathTextArea_labeledPoints.Value = app.labeled_points('filename'); 

                % Add attributes to the color grouping selector
                labels = readtable(app.labeled_points('filename'));
                vars = labels.Properties.VariableNames;
                app.ColorpointsbyDropDown.Items = vars;
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: ClearcolorsButton
        function ClearcolorsButtonPushed(app, event)
            app.track_colors = [];
            app.UITable_colors.Data = [];
        end

        % Button pushed function: AddcolorButton
        function AddcolorButtonPushed(app, event)
            c = uisetcolor;
            if length(c) == 3
                app.track_colors(height(app.track_colors) + 1,:) = c;
                app.UITable_colors.Data = app.track_colors;
            end

            if height(app.track_colors) >= 30
                app.AddcolorButton.Enable='off';
           end
        end

        % Button pushed function: ClearallshapefilesButton
        function ClearallshapefilesButtonPushed(app, event)
            try
                app.shapefile_stack = {};
                app.UITable_shapefiles.Data = [];
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: ClearlastshapefileButton
        function ClearlastshapefileButtonPushed(app, event)
            try
                app.shapefile_stack(end) = [];
                app.UITable_shapefiles.Data = app.UITable_shapefiles.Data(1:end-1, :);
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            try
                app.update_status_text("Loading settings...");
                [settings_file, settings_path] = uigetfile('*.mat', "Load settings");
                file = fullfile(settings_path, settings_file);
                if file
                   load(file, 'props', 'values', 'data', 'items', 'visibilities', 'var_map');
                end

                % Reset the individuals tree node 
                individual_nodes = app.AllindividualsNode.Children;
                individual_nodes.delete;

                % Re-populate widgets that were dependent on the track data
                keys = var_map.keys;
                for i=1:length(keys)
                    app.(keys{i}) = var_map(keys{i});
                end
                app.update_widgets_from_track_data();

                % Re-populate the selected individuals in the tree
                checkedNodes = matlab.ui.container.TreeNode.empty(length(app.selected_individuals), 0);
                for i = 1:length(app.selected_individuals)
                    checkedNodes(i) = app.Tree.findobj('Text', app.selected_individuals{i});
                end
                app.Tree.CheckedNodes = checkedNodes;

                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Items')
                        app.(propName).Items = items{i};
                    end
                    if isprop(property, 'Value')
                        app.(propName).Value = values{i};
                    end
                    if isprop(property, 'Data')
                        app.(propName).Data = data{i};
                    end
                    if isprop(property, 'Visible')
                        app.(props{i}).Visible = visibilities{i};
                    end
                end
                
                app.update_status_text("Settings loaded from " + file);
            catch e
                app.update_status_text("Error loading settings..." + newline + newline + e.getReport);
            end
        end

        % Button pushed function: CleardataButton_gridded
        function Clear_gridded_data_ButtonPushed(app, event)
            app.gridded_data = {};
            app.FilepathTextArea_gridded.Value = '';
            app.TimevariableDropDown_gridded.Items = {};
            app.LatitudevariableDropDown_gridded.Items = {};
            app.LongitudevariableDropDown_gridded.Items = {};
            app.VariabletoanimateDropDown_gridded.Items = {};
            app.ColormapDropDown_gridded.Value = app.ColormapDropDown_gridded.Items(1);
            app.InvertcolormapCheckBox.Value = false;
            app.ColorbarminEditField.Value = 0;
            app.ColorbarmaxEditField.Value = 1;
            app.AutomaticcolorbarlimitsCheckBox.Value = false; 
            app.ShowcolorbarCheckBox.Value = true;

        end

        % Button pushed function: CleardataButton_contour
        function Clear_contour_data_ButtonPushed(app, event)
            app.contour_data = containers.Map();
            app.FilepathTextArea_contour.Value = '';
            app.TimevariableDropDown_contour.Items = {};
            app.LatitudevariableDropDown_contour.Items = {};
            app.LongitudevariableDropDown_contour.Items = {};
            app.VariabletoanimateDropDown_contour.Items = {};
            app.ContourlinecolorDropDown.Value = app.ContourlinecolorDropDown.Items(1);
            app.ContourlinewidthSpinner.Value = 0.7;
            app.ShowcontourtextCheckBox.Value = false;
        end

        % Button pushed function: CleardataButton_labeledPoints
        function CleardataButton_labeledPointsPushed(app, event)
            app.labeled_points = containers.Map();
            app.FilepathTextArea_labeledPoints.Value = '';
            app.MarkercolorDropDown_labeledPoints.Value = app.MarkercolorDropDown_labeledPoints.Items(1);
            app.MarkersizeSpinner_labeledPoints.Value = 20;
        end

        % Value changed function: PointcolorDropDown_shapefile
        function PointcolorDropDown_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.point_color = app.PointcolorDropDown_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.PointcolorDropDown_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end   
        end

        % Value changed function: MarkerstyleDropDown_shapefile
        function MarkerstyleDropDown_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.marker_style = app.MarkerstyleDropDown_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Marker style") = {app.MarkerstyleDropDown_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end              
        end

        % Value changed function: MarkersizeSpinner_shapefile
        function MarkersizeSpinner_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.marker_size = app.MarkersizeSpinner_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Marker size") = {app.MarkersizeSpinner_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end  
        end

        % Button pushed function: SelectvectorfielddataButton
        function SelectvectorfielddataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [datafile, path] = uigetfile('*.nc', 'select');
                app.quiver_data = Quivers(fullfile(path, datafile));
                app.FilepathTextArea_vectorfield.Value = app.quiver_data.filename;
                
                % Get variable names 
                app.U_vector_DropDown.Items = app.quiver_data.varnames;
                app.V_vector_DropDown.Items = app.quiver_data.varnames;
                app.TimevariableDropDown_vectorfield.Items = app.quiver_data.varnames; 
                app.LatitudevariableDropDown_vectorfield.Items = app.quiver_data.varnames; 
                app.LongitudevariableDropDown_vectorfield.Items = app.quiver_data.varnames;
                
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.quiver_data, 'TimevariableDropDown_vectorfield', ...
                    'LatitudevariableDropDown_vectorfield', 'LongitudevariableDropDown_vectorfield', ...
                    struct('u', 'U_vector_DropDown', 'v', 'V_vector_DropDown'))
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: CleardataButton_vectorfield
        function CleardataButton_vectorfieldPushed(app, event)
            app.quiver_data = {};
            app.FilepathTextArea_vectorfield.Value = '';
            app.TimevariableDropDown_vectorfield.Items = {};
            app.LatitudevariableDropDown_vectorfield.Items = {};
            app.LongitudevariableDropDown_vectorfield.Items = {};
            app.U_vector_DropDown.Items = {};
            app.V_vector_DropDown.Items = {};
            app.QuivercolorDropDown.Value = app.QuivercolorDropDown.Items(1);
        end

        % Button pushed function: AddcolorButton_labeledPoints
        function AddcolorButton_labeledPointsPushed(app, event)
            c = uisetcolor;
            if length(c) == 3
                app.labeled_points_colors(height(app.labeled_points_colors) + 1,:) = c;
                app.UITable_colors_labeledpoints.Data = app.labeled_points_colors;
            end

            if height(app.labeled_points_colors) >= 30
                app.AddcolorButton_labeledPoints.Enable='off';
           end
        end

        % Button pushed function: ClearcolorsButton_labeledPoints
        function ClearcolorsButton_labeledPointsPushed(app, event)
            app.labeled_points_colors = [];
            app.UITable_colors_labeledpoints.Data = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECODATAAnimateUIFigure = uifigure('Visible', 'off');
            app.ECODATAAnimateUIFigure.Name = 'ECODATA-Animate';
            app.CreateanimationButton = uibutton(app.ECODATAAnimateUIFigure, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.StatusTextAreaLabel = uilabel(app.ECODATAAnimateUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ECODATAAnimateUIFigure);
            app.TabGroup = uitabgroup(app.ECODATAAnimateUIFigure);
            app.AnimaltrackdataTab = uitab(app.TabGroup);
            app.AnimaltrackdataTab.Title = 'Animal track data';
            app.SelecttrackdataButton = uibutton(app.AnimaltrackdataTab, 'push');
            app.SelecttrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrackdataButtonPushed, true);
            app.SelecttrackdataButton.Text = 'Select track data';
            app.FilepathTextAreaLabel = uilabel(app.AnimaltrackdataTab);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea_tracks = uitextarea(app.AnimaltrackdataTab);
            app.FilepathTextArea_tracks.Editable = 'off';
            app.FilepathTextArea_tracks.Enable = 'off';
            app.DatafiltersPanel = uipanel(app.AnimaltrackdataTab);
            app.DatafiltersPanel.Title = 'Data filters:';
            app.Tree = uitree(app.DatafiltersPanel, 'checkbox');
            app.AllindividualsNode = uitreenode(app.Tree);
            app.AllindividualsNode.Text = 'All individuals';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.IndividualsLabel = uilabel(app.DatafiltersPanel);
            app.IndividualsLabel.Text = 'Individuals: ';
            app.TimerangeLabel = uilabel(app.DatafiltersPanel);
            app.TimerangeLabel.Text = 'Time range: ';
            app.GeographicextentLabel = uilabel(app.DatafiltersPanel);
            app.GeographicextentLabel.Text = 'Geographic extent: ';
            app.UpdatefiltersButton = uibutton(app.DatafiltersPanel, 'push');
            app.UpdatefiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefiltersButtonPushed, true);
            app.UpdatefiltersButton.Text = 'Update filters';
            app.SelectionsummaryLabel = uilabel(app.DatafiltersPanel);
            app.SelectionsummaryLabel.Text = 'Selection summary: ';
            app.StartdateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.EnddateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.MinlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.NumberofindividualsselectedTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofindividualsselectedTextAreaLabel.Text = 'Number of individuals selected:';
            app.NumberofindividualsselectedTextArea = uitextarea(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel.Text = 'Earliest timestamp in selection:';
            app.EarliesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel.Text = 'Latest timestamp in selection:';
            app.LatesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel.Text = 'Number of points in selection:';
            app.NumberofpointsinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.TracksvisualizationoptionsTab = uitab(app.TabGroup);
            app.TracksvisualizationoptionsTab.Title = 'Tracks visualization options';
            app.AddcolorButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.AddcolorButton.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButtonPushed, true);
            app.AddcolorButton.Text = 'Add color';
            app.FadetracksCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.FadetracksCheckBox.Text = 'Fade tracks';
            app.TrackmemoryEditFieldLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackmemoryEditFieldLabel.Text = 'Track memory';
            app.TrackmemoryEditField = uieditfield(app.TracksvisualizationoptionsTab, 'numeric');
            app.TrackmemoryEditField.Limits = [1 Inf];
            app.TrackmemoryEditField.Value = 20;
            app.MarkerstyleDropDownLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDownLabel.Text = 'Marker style';
            app.MarkerstyleDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDown.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown.Value = 'hexagram';
            app.TracklinewidthSpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinnerLabel.Text = 'Track line width';
            app.TracklinewidthSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinner.Step = 0.1;
            app.TracklinewidthSpinner.Limits = [0.1 5];
            app.TracklinewidthSpinner.Value = 1;
            app.ColortracksbyDropDownLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDownLabel.Text = 'Color tracks by:';
            app.ColortracksbyDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDown.Items = {'individual_local_identifier'};
            app.ColortracksbyDropDown.Placeholder = 'Select attribute';
            app.ColortracksbyDropDown.Value = 'individual_local_identifier';
            app.TrackfrequencyhoursSpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinnerLabel.Text = 'Track frequency (hours)';
            app.TrackfrequencyhoursSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinner.Limits = [0.1 168];
            app.TrackfrequencyhoursSpinner.Value = 24;
            app.TrackopacitySpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinnerLabel.Text = 'Track opacity';
            app.TrackopacitySpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinner.Step = 0.1;
            app.TrackopacitySpinner.Limits = [0 1];
            app.TrackopacitySpinner.Value = 0.6;
            app.MarkersizeSpinner_2Label = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_2Label.Text = 'Marker size';
            app.MarkersizeSpinner_tracks = uispinner(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_tracks.Step = 5;
            app.MarkersizeSpinner_tracks.Limits = [1 500];
            app.MarkersizeSpinner_tracks.Value = 150;
            app.ClearcolorsButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.ClearcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButtonPushed, true);
            app.ClearcolorsButton.Text = 'Clear colors';
            app.ShowlegendCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.ShowlegendCheckBox.Text = 'Show legend';
            app.ShowlegendCheckBox.Value = true;
            app.UseblackmarkersCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.UseblackmarkersCheckBox.Text = 'Use black markers';
            app.UsecustomcolorsCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.UsecustomcolorsCheckBox.Text = 'Use custom colors';
            app.UITable_colors = uitable(app.TracksvisualizationoptionsTab);
            app.UITable_colors.ColumnName = {'R'; 'G'; 'B'};
            app.UITable_colors.RowName = {};
            app.VisualizationoptionsLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.VisualizationoptionsLabel.Text = 'Visualization options:';
            app.EnvironmentaldataTab = uitab(app.TabGroup);
            app.EnvironmentaldataTab.Title = 'Environmental data';
            app.TabGroup2 = uitabgroup(app.EnvironmentaldataTab);
            app.GriddedcolormapTab = uitab(app.TabGroup2);
            app.GriddedcolormapTab.Title = 'Gridded colormap';
            app.SelectgriddeddataButton = uibutton(app.GriddedcolormapTab, 'push');
            app.SelectgriddeddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButtonPushed, true);
            app.SelectgriddeddataButton.Text = 'Select gridded data';
            app.InvertcolormapCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.InvertcolormapCheckBox.Text = 'Invert color map';
            app.CleardataButton_gridded = uibutton(app.GriddedcolormapTab, 'push');
            app.CleardataButton_gridded.ButtonPushedFcn = createCallbackFcn(app, @Clear_gridded_data_ButtonPushed, true);
            app.CleardataButton_gridded.Text = 'Clear data';
            app.ShowcolorbarCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.ShowcolorbarCheckBox.Text = 'Show colorbar';
            app.ShowcolorbarCheckBox.Value = true;
            app.AutomaticcolorbarlimitsCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.AutomaticcolorbarlimitsCheckBox.Text = 'Automatic colorbar limits';
            app.ColormapDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.ColormapDropDown_gridded.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown_gridded.Value = 'green';
            app.TimevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.TimevariableDropDown_gridded.Items = {};
            app.TimevariableDropDown_gridded.Placeholder = 'Select variable';
            app.TimevariableDropDown_gridded.Value = {};
            app.TimevariableLabel = uilabel(app.GriddedcolormapTab);
            app.TimevariableLabel.Text = 'Time variable:';
            app.LatitudevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.LatitudevariableDropDown_gridded.Items = {};
            app.LatitudevariableDropDown_gridded.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_gridded.Value = {};
            app.LatitudevariableLabel = uilabel(app.GriddedcolormapTab);
            app.LatitudevariableLabel.Text = 'Latitude variable:';
            app.LongitudevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.LongitudevariableDropDown_gridded.Items = {};
            app.LongitudevariableDropDown_gridded.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_gridded.Value = {};
            app.LongitudevariableDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.LongitudevariableDropDownLabel.Text = 'Longitude variable:';
            app.FilepathTextArea_2Label = uilabel(app.GriddedcolormapTab);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.FilepathTextArea_gridded = uitextarea(app.GriddedcolormapTab);
            app.FilepathTextArea_gridded.Editable = 'off';
            app.FilepathTextArea_gridded.Enable = 'off';
            app.ColorbarminEditFieldLabel = uilabel(app.GriddedcolormapTab);
            app.ColorbarminEditFieldLabel.Text = 'Colorbar min';
            app.ColorbarminEditField = uieditfield(app.GriddedcolormapTab, 'numeric');
            app.VariabletoanimateDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.VariabletoanimateDropDownLabel.Text = 'Variable to animate:';
            app.VariabletoanimateDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.VariabletoanimateDropDown_gridded.Items = {};
            app.VariabletoanimateDropDown_gridded.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_gridded.Value = {};
            app.ColorbarmaxEditFieldLabel = uilabel(app.GriddedcolormapTab);
            app.ColorbarmaxEditFieldLabel.Text = 'Colorbar max';
            app.ColorbarmaxEditField = uieditfield(app.GriddedcolormapTab, 'numeric');
            app.ColorbarmaxEditField.Value = 1;
            app.DatasetdimensionsLabel = uilabel(app.GriddedcolormapTab);
            app.DatasetdimensionsLabel.Text = 'Dataset dimensions:';
            app.ColoroptionsLabel = uilabel(app.GriddedcolormapTab);
            app.ColoroptionsLabel.Text = 'Color options:';
            app.GriddeddataLabel = uilabel(app.GriddedcolormapTab);
            app.GriddeddataLabel.Text = 'Gridded data:';
            app.ContoursTab = uitab(app.TabGroup2);
            app.ContoursTab.Title = 'Contours';
            app.ContourdataLabel = uilabel(app.ContoursTab);
            app.ContourdataLabel.Text = 'Contour data:';
            app.SelectcontourdataButton = uibutton(app.ContoursTab, 'push');
            app.SelectcontourdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcontourdataButtonPushed, true);
            app.SelectcontourdataButton.Text = 'Select contour data';
            app.FilepathTextArea_contour = uitextarea(app.ContoursTab);
            app.FilepathTextArea_contour.Editable = 'off';
            app.FilepathTextArea_contour.Enable = 'off';
            app.VariabletoanimateDropDown_contour = uidropdown(app.ContoursTab);
            app.VariabletoanimateDropDown_contour.Items = {};
            app.VariabletoanimateDropDown_contour.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_contour.Value = {};
            app.TimevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.TimevariableDropDown_contour.Items = {};
            app.TimevariableDropDown_contour.Placeholder = 'Select variable';
            app.TimevariableDropDown_contour.Value = {};
            app.LatitudevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.LatitudevariableDropDown_contour.Items = {};
            app.LatitudevariableDropDown_contour.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_contour.Value = {};
            app.LongitudevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.LongitudevariableDropDown_contour.Items = {};
            app.LongitudevariableDropDown_contour.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_contour.Value = {};
            app.ShowcontourtextCheckBox = uicheckbox(app.ContoursTab);
            app.ShowcontourtextCheckBox.Text = 'Show contour text';
            app.VariabletoanimateDropDownLabel_2 = uilabel(app.ContoursTab);
            app.VariabletoanimateDropDownLabel_2.Text = 'Variable to animate:';
            app.TimevariableLabel_2 = uilabel(app.ContoursTab);
            app.TimevariableLabel_2.Text = 'Time variable:';
            app.LatitudevariableLabel_2 = uilabel(app.ContoursTab);
            app.LatitudevariableLabel_2.Text = 'Latitude variable:';
            app.LongitudevariableDropDownLabel_2 = uilabel(app.ContoursTab);
            app.LongitudevariableDropDownLabel_2.Text = 'Longitude variable:';
            app.CleardataButton_contour = uibutton(app.ContoursTab, 'push');
            app.CleardataButton_contour.ButtonPushedFcn = createCallbackFcn(app, @Clear_contour_data_ButtonPushed, true);
            app.CleardataButton_contour.Text = 'Clear data';
            app.FilepathTextArea_2Label_6 = uilabel(app.ContoursTab);
            app.FilepathTextArea_2Label_6.Text = 'File path';
            app.ContourlinecolorDropDownLabel = uilabel(app.ContoursTab);
            app.ContourlinecolorDropDownLabel.Text = 'Contour line color';
            app.ContourlinecolorDropDown = uidropdown(app.ContoursTab);
            app.ContourlinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown.Editable = 'on';
            app.ContourlinecolorDropDown.Value = 'black';
            app.ContourlinewidthSpinnerLabel = uilabel(app.ContoursTab);
            app.ContourlinewidthSpinnerLabel.Text = 'Contour line width';
            app.ContourlinewidthSpinner = uispinner(app.ContoursTab);
            app.ContourlinewidthSpinner.Step = 0.1;
            app.ContourlinewidthSpinner.Limits = [0.2 3];
            app.ContourlinewidthSpinner.Value = 0.7;
            app.DatasetdimensionsLabel_2 = uilabel(app.ContoursTab);
            app.DatasetdimensionsLabel_2.Text = 'Dataset dimensions:';
            app.ContouroptionsLabel = uilabel(app.ContoursTab);
            app.ContouroptionsLabel.Text = 'Contour options:';
            app.VectorfieldsTab = uitab(app.TabGroup2);
            app.VectorfieldsTab.Title = 'Vector fields';
            app.SelectvectorfielddataButton = uibutton(app.VectorfieldsTab, 'push');
            app.SelectvectorfielddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectvectorfielddataButtonPushed, true);
            app.SelectvectorfielddataButton.Text = 'Select vector field data';
            app.CleardataButton_vectorfield = uibutton(app.VectorfieldsTab, 'push');
            app.CleardataButton_vectorfield.ButtonPushedFcn = createCallbackFcn(app, @CleardataButton_vectorfieldPushed, true);
            app.CleardataButton_vectorfield.Text = 'Clear data';
            app.VectorfielddatawindcurrentsLabel = uilabel(app.VectorfieldsTab);
            app.VectorfielddatawindcurrentsLabel.Text = 'Vector field data (wind, currents):';
            app.VvectorLabel = uilabel(app.VectorfieldsTab);
            app.VvectorLabel.Text = 'V vector';
            app.V_vector_DropDown = uidropdown(app.VectorfieldsTab);
            app.V_vector_DropDown.Items = {};
            app.V_vector_DropDown.Placeholder = 'Select variable';
            app.V_vector_DropDown.Value = {};
            app.FilepathTextArea_2Label_7 = uilabel(app.VectorfieldsTab);
            app.FilepathTextArea_2Label_7.Text = 'File path';
            app.FilepathTextArea_vectorfield = uitextarea(app.VectorfieldsTab);
            app.FilepathTextArea_vectorfield.Editable = 'off';
            app.FilepathTextArea_vectorfield.Enable = 'off';
            app.TimevariableLabel_3 = uilabel(app.VectorfieldsTab);
            app.TimevariableLabel_3.Text = 'Time variable:';
            app.TimevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.TimevariableDropDown_vectorfield.Items = {};
            app.TimevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.TimevariableDropDown_vectorfield.Value = {};
            app.LatitudevariableLabel_3 = uilabel(app.VectorfieldsTab);
            app.LatitudevariableLabel_3.Text = 'Latitude variable:';
            app.LatitudevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.LatitudevariableDropDown_vectorfield.Items = {};
            app.LatitudevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_vectorfield.Value = {};
            app.LongitudevariableDropDownLabel_3 = uilabel(app.VectorfieldsTab);
            app.LongitudevariableDropDownLabel_3.Text = 'Longitude variable:';
            app.LongitudevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.LongitudevariableDropDown_vectorfield.Items = {};
            app.LongitudevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_vectorfield.Value = {};
            app.UvectorLabel = uilabel(app.VectorfieldsTab);
            app.UvectorLabel.Text = 'U vector';
            app.U_vector_DropDown = uidropdown(app.VectorfieldsTab);
            app.U_vector_DropDown.Items = {};
            app.U_vector_DropDown.Placeholder = 'Select variable';
            app.U_vector_DropDown.Value = {};
            app.DatasetdimensionsLabel_3 = uilabel(app.VectorfieldsTab);
            app.DatasetdimensionsLabel_3.Text = 'Dataset dimensions:';
            app.VectoroptionsLabel = uilabel(app.VectorfieldsTab);
            app.VectoroptionsLabel.Text = 'Vector options:';
            app.ContourlinecolorDropDown_2Label_2 = uilabel(app.VectorfieldsTab);
            app.ContourlinecolorDropDown_2Label_2.Text = 'Quiver color';
            app.QuivercolorDropDown = uidropdown(app.VectorfieldsTab);
            app.QuivercolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.QuivercolorDropDown.Editable = 'on';
            app.QuivercolorDropDown.Value = 'black';
            app.ContourlinewidthSpinner_2Label_2 = uilabel(app.VectorfieldsTab);
            app.ContourlinewidthSpinner_2Label_2.Visible = 'off';
            app.ContourlinewidthSpinner_2Label_2.Text = 'Quiver size';
            app.QuiversizeSpinner = uispinner(app.VectorfieldsTab);
            app.QuiversizeSpinner.Step = 0.1;
            app.QuiversizeSpinner.Limits = [0.01 3];
            app.QuiversizeSpinner.Visible = 'off';
            app.QuiversizeSpinner.Value = 0.5;
            app.QuiverspeedSpinnerLabel = uilabel(app.VectorfieldsTab);
            app.QuiverspeedSpinnerLabel.Visible = 'off';
            app.QuiverspeedSpinnerLabel.Text = 'Quiver speed';
            app.QuiverspeedSpinner = uispinner(app.VectorfieldsTab);
            app.QuiverspeedSpinner.Limits = [0.5 10];
            app.QuiverspeedSpinner.Visible = 'off';
            app.QuiverspeedSpinner.Value = 1;
            app.AutoscaleCheckBox = uicheckbox(app.VectorfieldsTab);
            app.AutoscaleCheckBox.Visible = 'off';
            app.AutoscaleCheckBox.Text = 'Auto-scale ';
            app.ShapefilesTab = uitab(app.TabGroup);
            app.ShapefilesTab.Title = 'Shapefiles';
            app.SelectshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButtonPushed, true);
            app.SelectshapefileButton.Text = 'Select shapefile';
            app.FilepathTextArea_shapefile = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_shapefile.Editable = 'off';
            app.FilepathTextArea_shapefile.Enable = 'off';
            app.FilepathTextArea_2Label_2 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_2.Text = 'File path';
            app.LinecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.LinecolorDropDownLabel.Enable = 'off';
            app.LinecolorDropDownLabel.Text = 'Line color';
            app.LinecolorDropDown = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDownValueChanged, true);
            app.LinecolorDropDown.Enable = 'off';
            app.LinecolorDropDown.Value = 'black';
            app.LinewidthSpinnerLabel = uilabel(app.ShapefilesTab);
            app.LinewidthSpinnerLabel.Enable = 'off';
            app.LinewidthSpinnerLabel.Text = 'Line width';
            app.LinewidthSpinner = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner.Step = 0.1;
            app.LinewidthSpinner.Limits = [0.2 3];
            app.LinewidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinnerValueChanged, true);
            app.LinewidthSpinner.Editable = 'off';
            app.LinewidthSpinner.Enable = 'off';
            app.LinewidthSpinner.Value = 0.7;
            app.FacecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.FacecolorDropDownLabel.Enable = 'off';
            app.FacecolorDropDownLabel.Text = 'Face color';
            app.FacecolorDropDown = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDownValueChanged, true);
            app.FacecolorDropDown.Enable = 'off';
            app.FacecolorDropDown.Value = 'black';
            app.EdgecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDownLabel.Enable = 'off';
            app.EdgecolorDropDownLabel.Text = 'Edge color';
            app.EdgecolorDropDown = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDownValueChanged, true);
            app.EdgecolorDropDown.Enable = 'off';
            app.EdgecolorDropDown.Value = 'black';
            app.FaceopacitySpinnerLabel = uilabel(app.ShapefilesTab);
            app.FaceopacitySpinnerLabel.Enable = 'off';
            app.FaceopacitySpinnerLabel.Text = 'Face opacity';
            app.FaceopacitySpinner = uispinner(app.ShapefilesTab);
            app.FaceopacitySpinner.Step = 0.1;
            app.FaceopacitySpinner.Limits = [0 1];
            app.FaceopacitySpinner.ValueChangedFcn = createCallbackFcn(app, @FaceopacitySpinnerValueChanged, true);
            app.FaceopacitySpinner.Editable = 'off';
            app.FaceopacitySpinner.Enable = 'off';
            app.FaceopacitySpinner.Value = 1;
            app.UITable_shapefiles = uitable(app.ShapefilesTab);
            app.UITable_shapefiles.ColumnName = {'File'; 'Geometry'; 'Point/Line/Edge color'; 'Marker style'; 'Marker size'; 'Line width'; 'Face color'; 'Face opacity'};
            app.UITable_shapefiles.RowName = {};
            app.ClearallshapefilesButton = uibutton(app.ShapefilesTab, 'push');
            app.ClearallshapefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallshapefilesButtonPushed, true);
            app.ClearallshapefilesButton.Text = 'Clear all shapefiles';
            app.ClearlastshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.ClearlastshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastshapefileButtonPushed, true);
            app.ClearlastshapefileButton.Text = 'Clear last shapefile';
            app.LineoptionsLabel = uilabel(app.ShapefilesTab);
            app.LineoptionsLabel.Text = 'Line options:';
            app.PointoptionsLabel = uilabel(app.ShapefilesTab);
            app.PointoptionsLabel.Text = 'Point options:';
            app.PolygonoptionsLabel = uilabel(app.ShapefilesTab);
            app.PolygonoptionsLabel.Text = 'Polygon options:';
            app.PointcolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.PointcolorDropDownLabel.Enable = 'off';
            app.PointcolorDropDownLabel.Text = 'Point color';
            app.PointcolorDropDown_shapefile = uidropdown(app.ShapefilesTab);
            app.PointcolorDropDown_shapefile.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.PointcolorDropDown_shapefile.ValueChangedFcn = createCallbackFcn(app, @PointcolorDropDown_shapefileValueChanged, true);
            app.PointcolorDropDown_shapefile.Enable = 'off';
            app.PointcolorDropDown_shapefile.Value = 'black';
            app.MarkerstyleDropDown_2Label = uilabel(app.ShapefilesTab);
            app.MarkerstyleDropDown_2Label.Enable = 'off';
            app.MarkerstyleDropDown_2Label.Text = 'Marker style';
            app.MarkerstyleDropDown_shapefile = uidropdown(app.ShapefilesTab);
            app.MarkerstyleDropDown_shapefile.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown_shapefile.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown_shapefile.ValueChangedFcn = createCallbackFcn(app, @MarkerstyleDropDown_shapefileValueChanged, true);
            app.MarkerstyleDropDown_shapefile.Enable = 'off';
            app.MarkerstyleDropDown_shapefile.Value = 'hexagram';
            app.MarkersizeSpinnerLabel_2 = uilabel(app.ShapefilesTab);
            app.MarkersizeSpinnerLabel_2.Enable = 'off';
            app.MarkersizeSpinnerLabel_2.Text = 'Marker size';
            app.MarkersizeSpinner_shapefile = uispinner(app.ShapefilesTab);
            app.MarkersizeSpinner_shapefile.Limits = [0.2 100];
            app.MarkersizeSpinner_shapefile.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSpinner_shapefileValueChanged, true);
            app.MarkersizeSpinner_shapefile.Editable = 'off';
            app.MarkersizeSpinner_shapefile.Enable = 'off';
            app.MarkersizeSpinner_shapefile.Value = 1;
            app.LabeledpointsTab = uitab(app.TabGroup);
            app.LabeledpointsTab.Title = 'Labeled points';
            app.Labeledpoints = uilabel(app.LabeledpointsTab);
            app.Labeledpoints.Text = 'Labeled points';
            app.SelectfileButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.SelectfileButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_labeledPointsPushed, true);
            app.SelectfileButton_labeledPoints.Text = 'Select file';
            app.FilepathTextArea_labeledPoints = uitextarea(app.LabeledpointsTab);
            app.FilepathTextArea_labeledPoints.Editable = 'off';
            app.FilepathTextArea_labeledPoints.Enable = 'off';
            app.FilepathTextAreaLabel_labeledPoints = uilabel(app.LabeledpointsTab);
            app.FilepathTextAreaLabel_labeledPoints.Text = 'File path';
            app.MarkercolorDropDownLabel = uilabel(app.LabeledpointsTab);
            app.MarkercolorDropDownLabel.Text = 'Marker color';
            app.MarkercolorDropDown_labeledPoints = uidropdown(app.LabeledpointsTab);
            app.MarkercolorDropDown_labeledPoints.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.MarkercolorDropDown_labeledPoints.Editable = 'on';
            app.MarkercolorDropDown_labeledPoints.Value = 'red';
            app.MarkersizeSpinnerLabel = uilabel(app.LabeledpointsTab);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkersizeSpinner_labeledPoints = uispinner(app.LabeledpointsTab);
            app.MarkersizeSpinner_labeledPoints.Limits = [1 100];
            app.MarkersizeSpinner_labeledPoints.Editable = 'off';
            app.MarkersizeSpinner_labeledPoints.Value = 20;
            app.CleardataButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.CleardataButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @CleardataButton_labeledPointsPushed, true);
            app.CleardataButton_labeledPoints.Text = 'Clear data';
            app.ShowlabeltextCheckBox = uicheckbox(app.LabeledpointsTab);
            app.ShowlabeltextCheckBox.Text = 'Show label text';
            app.Labelcolor = uilabel(app.LabeledpointsTab);
            app.Labelcolor.Text = 'Label color';
            app.LabelcolorDropDown_labeledPoints = uidropdown(app.LabeledpointsTab);
            app.LabelcolorDropDown_labeledPoints.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LabelcolorDropDown_labeledPoints.Editable = 'on';
            app.LabelcolorDropDown_labeledPoints.Value = 'black';
            app.Labelsize = uilabel(app.LabeledpointsTab);
            app.Labelsize.Text = 'Label size';
            app.LabelsizeSpinner_labeledPoints = uispinner(app.LabeledpointsTab);
            app.LabelsizeSpinner_labeledPoints.Limits = [1 100];
            app.LabelsizeSpinner_labeledPoints.Editable = 'off';
            app.LabelsizeSpinner_labeledPoints.Value = 8;
            app.ColorpointsbyDropDownLabel = uilabel(app.LabeledpointsTab);
            app.ColorpointsbyDropDownLabel.Text = 'Color points by:';
            app.ColorpointsbyDropDown = uidropdown(app.LabeledpointsTab);
            app.ColorpointsbyDropDown.Items = {'None'};
            app.ColorpointsbyDropDown.Placeholder = 'Select attribute';
            app.ColorpointsbyDropDown.Value = 'None';
            app.ColorpointsbyattributeCheckBox = uicheckbox(app.LabeledpointsTab);
            app.ColorpointsbyattributeCheckBox.Text = 'Color points by attribute';
            app.AddcolorButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.AddcolorButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButton_labeledPointsPushed, true);
            app.AddcolorButton_labeledPoints.Text = 'Add color';
            app.ClearcolorsButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.ClearcolorsButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButton_labeledPointsPushed, true);
            app.ClearcolorsButton_labeledPoints.Text = 'Clear colors';
            app.UsecustomcolorsCheckBox_labeledPoints = uicheckbox(app.LabeledpointsTab);
            app.UsecustomcolorsCheckBox_labeledPoints.Text = 'Use custom colors';
            app.UITable_colors_labeledpoints = uitable(app.LabeledpointsTab);
            app.UITable_colors_labeledpoints.ColumnName = {'R'; 'G'; 'B'};
            app.UITable_colors_labeledpoints.RowName = {};
            app.ElevationTab = uitab(app.TabGroup);
            app.ElevationTab.Title = 'Elevation';
            app.ShowcontourtextCheckBox_elevation = uicheckbox(app.ElevationTab);
            app.ShowcontourtextCheckBox_elevation.Text = 'Show contour text';
            app.ContourlinewidthSpinner_2Label = uilabel(app.ElevationTab);
            app.ContourlinewidthSpinner_2Label.Text = 'Contour line width';
            app.ContourlinewidthSpinner_elevation = uispinner(app.ElevationTab);
            app.ContourlinewidthSpinner_elevation.Step = 0.1;
            app.ContourlinewidthSpinner_elevation.Limits = [0.2 3];
            app.ContourlinewidthSpinner_elevation.Value = 0.7;
            app.CheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox.Text = 'Include elevation contours';
            app.NumberoflevelsSpinnerLabel = uilabel(app.ElevationTab);
            app.NumberoflevelsSpinnerLabel.Text = 'Number of levels';
            app.NumberoflevelsSpinner = uispinner(app.ElevationTab);
            app.NumberoflevelsSpinner.Limits = [2 30];
            app.NumberoflevelsSpinner.Value = 10;
            app.ContourlinecolorDropDown_2Label = uilabel(app.ElevationTab);
            app.ContourlinecolorDropDown_2Label.Text = 'Contour line color';
            app.ContourlinecolorDropDown_elevation = uidropdown(app.ElevationTab);
            app.ContourlinecolorDropDown_elevation.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown_elevation.Editable = 'on';
            app.ContourlinecolorDropDown_elevation.Value = 'black';
            app.OutputPanel = uipanel(app.ECODATAAnimateUIFigure);
            app.OutputPanel.Title = 'Output:';
            app.SetoutputdirectoryButton = uibutton(app.OutputPanel, 'push');
            app.SetoutputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputdirectoryButtonPushed, true);
            app.SetoutputdirectoryButton.Text = 'Set output directory';
            app.FilepathTextArea_3Label = uilabel(app.OutputPanel);
            app.FilepathTextArea_3Label.Text = 'File path';
            app.FilepathTextArea_output = uitextarea(app.OutputPanel);
            app.FilepathTextArea_output.Editable = 'off';
            app.FilepathTextArea_output.Enable = 'off';
            app.GeneratelastframeonlyCheckBox = uicheckbox(app.OutputPanel);
            app.GeneratelastframeonlyCheckBox.Text = 'Generate last frame only';
            app.GeneratelastframeonlyCheckBox.Value = true;
            app.LoadsettingsButton = uibutton(app.ECODATAAnimateUIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            % Show the figure after all components are created
            app.ECODATAAnimateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECODATAAnimateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECODATAAnimateUIFigure)
        end
    end
end